classdef Input < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        SolveButton     matlab.ui.control.StateButton
        InputPanel      matlab.ui.container.Panel
        MassPanel       matlab.ui.container.Panel
        MfEditField     matlab.ui.control.NumericEditField
        Label_3         matlab.ui.control.Label
        kgLabel_3       matlab.ui.control.Label
        kgLabel_2       matlab.ui.control.Label
        MiEditField     matlab.ui.control.NumericEditField
        Label_2         matlab.ui.control.Label
        M0EditField     matlab.ui.control.NumericEditField
        Label           matlab.ui.control.Label
        kgLabel         matlab.ui.control.Label
        ForcePanel      matlab.ui.container.Panel
        ForceEditField  matlab.ui.control.NumericEditField
        ForceLabel      matlab.ui.control.Label
        NLabel          matlab.ui.control.Label
        TitlePanel      matlab.ui.container.Panel
        TitleLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        Outputshow % passing variables
        force = 0;
        mass_start = 0;
        mass_interval = 0;
        mass_final = 0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % initial values
            app.ForceEditField.Value = 5000;
            app.M0EditField.Value = 1;
            app.MiEditField.Value = 1;
            app.MfEditField.Value = 100;
            
            % assign values for variabls in property Output 
            app.force = app.ForceEditField.Value;
            app.mass_start = app.M0EditField.Value;
            app.mass_interval = app.MiEditField.Value;
            app.mass_final = app.MfEditField.Value;
            
        end

        % Value changed function: ForceEditField
        function ForceEditFieldValueChanged(app, event)
            value = app.ForceEditField.Value;
            app.force = value;
        end

        % Value changed function: M0EditField
        function M0EditFieldValueChanged(app, event)
            value = app.M0EditField.Value;
            app.mass_start = value;
        end

        % Value changed function: MiEditField
        function MiEditFieldValueChanged(app, event)
            value = app.MiEditField.Value;
            app.mass_interval = value;
        end

        % Value changed function: MfEditField
        function MfEditFieldValueChanged(app, event)
            value = app.MfEditField.Value;
            app.mass_final = value;
        end

        % Value changed function: SolveButton
        function SolveButtonValueChanged(app, event)
            value = app.SolveButton.Value;
            if value == 1
                app.Outputshow = Output(app,app.force,app.mass_start,...
                    app.mass_interval,app.mass_final);
                app.SolveButton.Value = 0;
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TitlePanel = uipanel(app.UIFigure);
            app.TitleLabel = uilabel(app.TitlePanel);
            app.TitleLabel.Text = 'Newton''s Second Law Solver';
            app.InputPanel = uipanel(app.UIFigure);
            app.InputPanel.Title = 'Input';
            app.ForcePanel = uipanel(app.InputPanel);
            app.NLabel = uilabel(app.ForcePanel);
            app.NLabel.Text = 'N';
            app.ForceLabel = uilabel(app.ForcePanel);
            app.ForceLabel.Text = 'Force';
            app.ForceEditField = uieditfield(app.ForcePanel, 'numeric');
            app.ForceEditField.ValueChangedFcn = createCallbackFcn(app, @ForceEditFieldValueChanged, true);
            app.MassPanel = uipanel(app.InputPanel);
            app.kgLabel = uilabel(app.MassPanel);
            app.kgLabel.Text = 'kg';
            app.Label = uilabel(app.MassPanel);
            app.Label.Text = 'Inital Mass';
            app.M0EditField = uieditfield(app.MassPanel, 'numeric');
            app.M0EditField.ValueChangedFcn = createCallbackFcn(app, @M0EditFieldValueChanged, true);
            app.Label_2 = uilabel(app.MassPanel);
            app.Label_2.Text = 'Mass Interval';
            app.MiEditField = uieditfield(app.MassPanel, 'numeric');
            app.MiEditField.ValueChangedFcn = createCallbackFcn(app, @MiEditFieldValueChanged, true);
            app.kgLabel_2 = uilabel(app.MassPanel);
            app.kgLabel_2.Text = 'kg';
            app.kgLabel_3 = uilabel(app.MassPanel);
            app.kgLabel_3.Text = 'kg';
            app.Label_3 = uilabel(app.MassPanel);
            app.Label_3.Text = 'Terminal Mass';
            app.MfEditField = uieditfield(app.MassPanel, 'numeric');
            app.MfEditField.ValueChangedFcn = createCallbackFcn(app, @MfEditFieldValueChanged, true);
            app.SolveButton = uibutton(app.UIFigure, 'state');
            app.SolveButton.ValueChangedFcn = createCallbackFcn(app, @SolveButtonValueChanged, true);
            app.SolveButton.Text = 'Solve';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Input

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end